classdef Interpulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolationUIFigure         matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        QueriesEditFieldLabel         matlab.ui.control.Label
        QueriesEditField              matlab.ui.control.NumericEditField
        InsertButton                  matlab.ui.control.Button
        EnterPointButton              matlab.ui.control.Button
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        SolveButton                   matlab.ui.control.Button
        NewButton                     matlab.ui.control.Button
        OrderEditFieldLabel           matlab.ui.control.Label
        OrderEditField                matlab.ui.control.NumericEditField
        polynomialEditFieldLabel      matlab.ui.control.Label
        polynomialEditField           matlab.ui.control.EditField
        SimplifiedEditFieldLabel      matlab.ui.control.Label
        SimplifiedEditField           matlab.ui.control.EditField
        FinishButton                  matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
        ChooseMethodDropDownLabel     matlab.ui.control.Label
        ChooseMethodDropDown          matlab.ui.control.DropDown
        ReadFromFileButton            matlab.ui.control.Button
        ExecutionTiimeEditFieldLabel  matlab.ui.control.Label
        ExecutionTiimeEditField       matlab.ui.control.NumericEditField
    end

%to do check not repeated x point;
    properties (Access = public)
        chooseMethod = 'Newton' ;
        xPoint = 0;
        yPoint = 0;
        myplot1;
        myplot2;
        matrix;
        Iteration = 1;
        order;
        function1;
        fun2;
        QueriesItr = 1;
        Queries = zeros(50);
        query_result = zeros(50);
        flag = 1; 
        takeFromgui = 1; 
        points;
    end

    methods (Access = public)
        function [final,final_Simplified_func] = NInterpolation(app)
            final = 0;
            final_Simplified_func = 0; 
            p = app.matrix(:,1);
            y = app.matrix(:,2);
            if (app.order >= length(app.matrix(:,1)))
                 prompt = {'ERROR!!! TOO FEW POINTS TO ACHIEVE THE ORDER'};
                 title = 'Error';
                 dims = [1 35];
                 errordlg(prompt,title,dims);
                 app.flag = 0;
            else 
            tic;
            b = zeros(1,length(p));
            temp = zeros(1,length(p));
             for i= 1:1:(app.order+1)
                 if(i == 1)
                     temp(i) = y(i);
                     b(i) = y(i);
                 else
                     temp(i) = (y(i)-y(i-1))/(p(i)- p(i-1));
                     b(i)= temp(i);
                 end
             end
            if (app.order > 1)
                k = 2;
               for i =3:1:length(b)
                   for j= i:1:length(b)
                       b(j) = (temp(j)-temp(j-1))/(p(j)- p(j-k));
                   end
                   temp = b;
                   k = k+1;
               end
            end
            syms term0(x)
            syms final(x)
            syms lastTerm(x)
            syms final_Simplified_func(x)
            final(x) = zeros(1,length(p));
            lastTerm(x) = zeros(1,length(p));
            term0(x) = zeros(1,2);
            for i= 1:1:length(p)
                if(i == 1)
                    term0(x) = b(i);
                    final(x) = term0(x);
                elseif(i==2)
                        lastTerm(x) = (b(i)*(x-p(i-1)));
                        final(x) = final(x)+lastTerm(x);
                else
                    lastTerm(x) = (lastTerm(x)*b(i)*((x-p(i-1))))/b(i-1);
                    final(x) = final(x)+lastTerm(x);
                end
            end
           % final_Simplified_func = simplifyFraction(final,'Expand',false);
           final_Simplified_func =simplifyFraction(expand(final));
            app.ExecutionTiimeEditField.Value = toc;
            app.myplot1 = fplot(app.UIAxes,final_Simplified_func,[app.matrix(1,1),app.matrix(length(app.matrix(:,1)),1)]);
            hold (app.UIAxes,'on');
            app.myplot2 = scatter(app.UIAxes,p,y,'r');
            legend(app.UIAxes,'Function','Point');
            end
        end 
        
    end

    methods (Access = private)
    
        function [final,final_Simplified_func] = Lagrange(app)
            final = 0;
            final_Simplified_func = 0; 
            p = app.matrix(:,1);
            y = app.matrix(:,2);
            if (app.order >= length(app.matrix(:,1)))
                 prompt = {'ERROR!!! TOO FEW POINTS TO ACHIEVE THE ORDER'};
                 title = 'Error';
                 dims = [1 35];
                 errordlg(prompt,title,dims);
                 app.flag = 0;
            else 
            syms term(x)
            syms final(x)
            syms final_Simplified_func(x)
            final(x) = zeros(1,length(p));
            term(x) = ones(1,length(p));
            final(x) = 0;
            tic;
             for i = 1:1:(app.order+1)
                 term(x) = 1;
                 for j= 1:1:(app.order+1)
                     if(j ~= i)
                         term(x) = term(x)*((x-p(j))/(p(i)-p(j)));
                     end
                 end
                 final(x) = final(x)+ term(x)*y(i);
             end
            final_Simplified_func =simplifyFraction(expand(final));
            app.ExecutionTiimeEditField.Value = toc;
            app.myplot1 = fplot(app.UIAxes,final_Simplified_func,[app.matrix(1,1),app.matrix(length(app.matrix(:,1)),1)]);
            hold (app.UIAxes,'on')
            app.myplot2 = scatter(app.UIAxes,p,y,'r');
            legend(app.UIAxes,'Function','Point');
            end
        end
      end


    methods (Access = private)

        % Value changed function: ChooseMethodDropDown
        function ChooseMethodDropDownValueChanged(app, event)
            app.chooseMethod = app.ChooseMethodDropDown.Value;           
        end

        % Button pushed function: EnterPointButton
        function EnterPointButtonPushed(app, event)
            app.ReadFromFileButton.Enable = 'off';
            temp = [app.xPoint,app.yPoint];
            if (app.Iteration == 1)
                 app.UITable.Data(app.Iteration,:) = temp;
                 app.Iteration = app.Iteration + 1;
            else
                if (~any(app.UITable.Data(:,1) == app.xPoint))
                app.UITable.Data(app.Iteration,:) = temp;
                app.Iteration = app.Iteration + 1; 
                else
                  prompt = {'ERROR!!! REPEATED X NODE'};
                     title = 'Error';
                     dims = [1 35];
                     errordlg(prompt,title,dims);   
                end 
            end   
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            if ~app.takeFromgui 
                for i = 1:1:(length(app.Queries))
                  app.UITable2.Data(i,1) = app.Queries(i);
                  app.query_result(i) = feval(app.function1,app.Queries(i));
                  app.UITable2.Data(i,2) = app.query_result(i);
                end
            else 
                 for i = 1:1:(app.QueriesItr-1)
                  app.UITable2.Data(i,1) = app.Queries(i);
                  app.query_result(i) = feval(app.function1,app.Queries(i));
                  app.UITable2.Data(i,2) = app.query_result(i);
                end
            end 
             hold (app.UIAxes,'on')
             app.points = scatter(app.UIAxes,app.UITable2.Data(:,1), app.UITable2.Data(:,2),'g','x');
             legend(app.UIAxes,'Function','Points','Quiries');
             app.UITable2.Visible = 'on';
             app.QueriesItr = 1; 
             hold (app.UIAxes,'off')
             app.FinishButton.Visible = 'off';
            app.InsertButton.Visible = 'off';
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
            value = app.QueriesEditField.Value;
            if (value > app.matrix(1,1) && value < app.matrix(app.order+1,1))   
                app.Queries(app.QueriesItr) = value;
                app.QueriesItr = app.QueriesItr + 1;
                app.takeFromgui = 1; 
            else 
               prompt = {'ERROR!!! QUIERY OUT OF  THE INTERVAL'};
               title = 'Error';
               dims = [1 35];
               errordlg(prompt,title,dims);  
            end 
               
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.ReadFromFileButton.Enable = 'on';
            app.SolveButton.Enable = 'on';
            app.EnterPointButton.Enable = 'on';
            app.matrix = [];
            app.UITable.Data = app.matrix;
            app.UITable2.Data = app.matrix;
            app.Iteration = 1;
            app.UITable2.Visible = 'off';
            hold (app.UIAxes,'off')
            app.FinishButton.Visible = 'off';
            app.InsertButton.Visible = 'off';
            app.flag = 1;
           delete(app.myplot1);
           delete(app.myplot2);
           delete(app.points);
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            app.order = app.OrderEditField.Value;          
        end

        % Button pushed function: ReadFromFileButton
        function ReadFromFileButtonPushed(app, event)
         app.EnterPointButton.Enable = 'off';
         app.SolveButton.Enable = 'off';
         app.takeFromgui = 0; 
        [file,filename ]= uigetfile('*.txt');
        [interpolationType,app.order,app.matrix(:,1),app.matrix(:,2),app.Queries,msg]  = readInterpolationFile(strcat(filename,file));
        if (msg == "")
            if(interpolationType == 1)
              [app.function1,app.fun2] = app.NInterpolation;
            else
              [app.function1,app.fun2] = app.Lagrange;
            end   
            if (app.flag == 1)  
                     app.UITable.Data = app.matrix;
                     app.polynomialEditField.Value = char(app.function1);
                     app.SimplifiedEditField.Value = char(app.fun2);
                     app.FinishButtonPushed();
                     app.FinishButton.Visible = 'on';
                     app.InsertButton.Visible = 'on';
            end 
        else 
               title = 'Error';
               dims = [1 35];
               errordlg(msg,title,dims);  
        end     
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
    
           if (length(app.UITable.Data) < 1) 
               prompt = {'ENTER THE POINT FIRST'};
               title = 'Error';
               dims = [1 35];
               errordlg(prompt,title,dims); 
           else
                app.matrix = sortrows(app.UITable.Data,1);   
                app.xPoint = app.matrix(app.Iteration-1,1);
                app.EnterPointButton.Enable = 'off';
                disp(app.matrix);
                switch  app.chooseMethod
                    case 'Newton'
                        [app.function1,app.fun2] = app.NInterpolation;
                    case 'Lagrang'
                        [app.function1,app.fun2] = app.Lagrange;
                    otherwise
                end 
             if (app.flag == 1)  
                 disp(app.function1);
                 disp(app.fun2);
                 app.polynomialEditField.Value = char(app.function1);
                 app.SimplifiedEditField.Value = char(app.fun2);
                 app.FinishButton.Visible = 'on';
                 app.InsertButton.Visible = 'on';
             end 
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.xPoint = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.yPoint = app.YEditField.Value;            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterpolationUIFigure = uifigure;
            app.InterpolationUIFigure.Name = 'Interpolation';
            app.UIAxes = uiaxes(app.InterpolationUIFigure);
            title(app.UIAxes, 'PLOT ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.QueriesEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.QueriesEditFieldLabel.Text = 'Queries ';
            app.QueriesEditField = uieditfield(app.InterpolationUIFigure, 'numeric');
            app.InsertButton = uibutton(app.InterpolationUIFigure, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Visible = 'off';
            app.InsertButton.Text = 'Insert';
            app.EnterPointButton = uibutton(app.InterpolationUIFigure, 'push');
            app.EnterPointButton.ButtonPushedFcn = createCallbackFcn(app, @EnterPointButtonPushed, true);
            app.EnterPointButton.Text = 'Enter Point ';
            app.XEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.InterpolationUIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InterpolationUIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.UITable = uitable(app.InterpolationUIFigure);
            app.UITable.ColumnName = {'X''s'; 'Y''s'};
            app.UITable.RowName = {};
            app.SolveButton = uibutton(app.InterpolationUIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.NewButton = uibutton(app.InterpolationUIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New ';
            app.OrderEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.InterpolationUIFigure, 'numeric');
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.polynomialEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.polynomialEditFieldLabel.Text = 'polynomial';
            app.polynomialEditField = uieditfield(app.InterpolationUIFigure, 'text');
            app.polynomialEditField.Editable = 'off';
            app.SimplifiedEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.SimplifiedEditFieldLabel.Text = 'Simplified';
            app.SimplifiedEditField = uieditfield(app.InterpolationUIFigure, 'text');
            app.SimplifiedEditField.Editable = 'off';
            app.FinishButton = uibutton(app.InterpolationUIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Visible = 'off';
            app.FinishButton.Text = 'Finish ';
            app.UITable2 = uitable(app.InterpolationUIFigure);
            app.UITable2.ColumnName = {'Quieries'; 'Value'};
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
            app.ChooseMethodDropDownLabel = uilabel(app.InterpolationUIFigure);
            app.ChooseMethodDropDownLabel.Text = 'Choose Method';
            app.ChooseMethodDropDown = uidropdown(app.InterpolationUIFigure);
            app.ChooseMethodDropDown.Items = {'Newton ', 'Lagrang'};
            app.ChooseMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMethodDropDownValueChanged, true);
            app.ChooseMethodDropDown.Value = 'Newton ';
            app.ReadFromFileButton = uibutton(app.InterpolationUIFigure, 'push');
            app.ReadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFromFileButtonPushed, true);
            app.ReadFromFileButton.Text = 'Read From File';
            app.ExecutionTiimeEditFieldLabel = uilabel(app.InterpolationUIFigure);
            app.ExecutionTiimeEditFieldLabel.Text = 'Execution Tiime';
            app.ExecutionTiimeEditField = uieditfield(app.InterpolationUIFigure, 'numeric');
            app.ExecutionTiimeEditField.Value = -Inf;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interpulation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterpolationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterpolationUIFigure)
        end
    end
end